classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        SaveUserPreferences             matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        CellSelectionTab                matlab.ui.container.Tab
        InputDataFolderEditFieldLabel   matlab.ui.control.Label
        InputDataFolderEditField        matlab.ui.control.EditField
        BrowseInputButton               matlab.ui.control.Button
        OutputDataFolderLabel           matlab.ui.control.Label
        OutputDataFolderEditField       matlab.ui.control.EditField
        BrowseOutputButton              matlab.ui.control.Button
        FoldersfoundinInputdirectoryLabel  matlab.ui.control.Label
        FolderListBox                   matlab.ui.control.ListBox
        ButtonSubmitFolders             matlab.ui.control.Button
        TableInputFolder                matlab.ui.control.Table
        IWF_Logo_1                      matlab.ui.control.UIAxes
        ProgrammFolderLabel             matlab.ui.control.Label
        ProgrammDataFolderEditField     matlab.ui.control.EditField
        BrowseProgrammButton            matlab.ui.control.Button
        ImagesfoundinselectedFoldersLabel  matlab.ui.control.Label
        ImageImportTab                  matlab.ui.container.Tab
        IWF_Logo_2                      matlab.ui.control.UIAxes
        ConfigurationforpicturestakeninXDirectionPanel  matlab.ui.container.Panel
        MinimalelectrodelengthpxSliderLabel  matlab.ui.control.Label
        MinimalelectrodelengthpxSlider  matlab.ui.control.Slider
        DistancefromcornerimagesSliderLabel  matlab.ui.control.Label
        DistancefromcornerimagesSlider  matlab.ui.control.Slider
        ConfigurationforpicturestakeninYDirectionPanel  matlab.ui.container.Panel
        MInmalelectrodelengthpxSliderLabel  matlab.ui.control.Label
        MInmalelectrodelengthpxSlider   matlab.ui.control.Slider
        MinimaldistancetoedgepxSliderLabel  matlab.ui.control.Label
        MinimaldistancetoedgepxSlider   matlab.ui.control.Slider
        ConfigurationforpicturestakeninZDirectionPanel  matlab.ui.container.Panel
        NumerofPicturesSliderLabel      matlab.ui.control.Label
        NumerofPicturesSlider           matlab.ui.control.Slider
        ShowHelperPreview               matlab.ui.control.UIAxes
        StartImportButton               matlab.ui.control.Button
    end


    properties (Access = public)
        
        FolderSelection = struct; % Description
        ImageSelection = struct;
        Distances = struct;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Test_Mode = true;
            appstart(app,Test_Mode);
            %imshow('IWF Logo.png', 'Parent', app.IWF_Logo_1);
            
        end

        % Button pushed function: BrowseInputButton
        function BrowseInputButtonPushed(app, event)
           FolderPicker(app,"input");
        end

        % Button pushed function: BrowseOutputButton
        function BrowseOutputButtonPushed(app, event)
           FolderPicker(app,"output");
        end

        % Button pushed function: ButtonSubmitFolders
        function ButtonSubmitFoldersPushed(app, event)
            app.FolderListBox.Enable = 'off';
            app.TableInputFolder.Enable = 'off';
        end

        % Value changing function: MinimalelectrodelengthpxSlider
        function MinimalelectrodelengthpxSliderValueChanging(app, event)
            imshow('AppImages/xMinLength.png', 'Parent', app.ShowHelperPreview);
            
        end

        % Value changing function: DistancefromcornerimagesSlider
        function DistancefromcornerimagesSliderValueChanging(app, event)
            imshow('AppImages/xDistToMiddle.png', 'Parent', app.ShowHelperPreview);
            
        end

        % Value changing function: MInmalelectrodelengthpxSlider
        function MInmalelectrodelengthpxSliderValueChanging(app, event)
            imshow('AppImages/yMinLength.png', 'Parent', app.ShowHelperPreview);
            
        end

        % Value changing function: MinimaldistancetoedgepxSlider
        function MinimaldistancetoedgepxSliderValueChanging(app, event)
            imshow('AppImages/yDistToEdge.png', 'Parent', app.ShowHelperPreview);
            
        end

        % Button pushed function: StartImportButton
        function StartImportButtonPushed(app, event)
            images_to_process_new(app);
            
        end

        % Menu selected function: SaveUserPreferences
        function SaveUserPreferencesMenuSelected(app, event)
            CreateUserPref(app);
        end

        % Value changed function: MinimalelectrodelengthpxSlider
        function MinimalelectrodelengthpxSliderValueChanged(app, event)
            app.ImageSelection.ImportSettings.xMinLength = ceil(app.MinimalelectrodelengthpxSlider.Value);     
        end

        % Value changed function: DistancefromcornerimagesSlider
        function DistancefromcornerimagesSliderValueChanged(app, event)
            app.ImageSelection.ImportSettings.xDistToMiddle =  ceil(app.DistancefromcornerimagesSlider.Value);
        end

        % Value changed function: MInmalelectrodelengthpxSlider
        function MInmalelectrodelengthpxSliderValueChanged(app, event)
            app.ImageSelection.ImportSettings.yMinLength =   ceil(app.MInmalelectrodelengthpxSlider.Value);
        end

        % Value changed function: MinimaldistancetoedgepxSlider
        function MinimaldistancetoedgepxSliderValueChanged(app, event)
            app.ImageSelection.ImportSettings.yDistToEdge =  ceil(app.MinimaldistancetoedgepxSlider.Value);
        end

        % Value changed function: NumerofPicturesSlider
        function NumerofPicturesSliderValueChanged(app, event)
            app.ImageSelection.ImportSettings.zAmount =  ceil(app.NumerofPicturesSlider.Value);
        end

        % Value changed function: FolderListBox
        function FolderListBoxValueChanged(app, event)
            app.FolderSelection.InputFolders.Selected_Values = app.FolderListBox.Value;
            app.TableInputFolder.Data = CreateTable(app,"Load");
        end

        % Button pushed function: BrowseProgrammButton
        function BrowseProgrammButtonPushed(app, event)
            FolderPicker(app,"programm");
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.SaveUserPreferences = uimenu(app.Menu);
            app.SaveUserPreferences.MenuSelectedFcn = createCallbackFcn(app, @SaveUserPreferencesMenuSelected, true);
            app.SaveUserPreferences.Text = 'Save User Preferences';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CellSelectionTab = uitab(app.TabGroup);
            app.CellSelectionTab.Title = 'Cell Selection';
            app.InputDataFolderEditFieldLabel = uilabel(app.CellSelectionTab);
            app.InputDataFolderEditFieldLabel.Text = 'Input Data Folder';
            app.InputDataFolderEditField = uieditfield(app.CellSelectionTab, 'text');
            app.BrowseInputButton = uibutton(app.CellSelectionTab, 'push');
            app.BrowseInputButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseInputButtonPushed, true);
            app.BrowseInputButton.Text = 'Browse';
            app.OutputDataFolderLabel = uilabel(app.CellSelectionTab);
            app.OutputDataFolderLabel.Text = 'Output Data Folder';
            app.OutputDataFolderEditField = uieditfield(app.CellSelectionTab, 'text');
            app.BrowseOutputButton = uibutton(app.CellSelectionTab, 'push');
            app.BrowseOutputButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseOutputButtonPushed, true);
            app.BrowseOutputButton.Text = 'Browse';
            app.FoldersfoundinInputdirectoryLabel = uilabel(app.CellSelectionTab);
            app.FoldersfoundinInputdirectoryLabel.Text = 'Folders found in Input directory';
            app.FolderListBox = uilistbox(app.CellSelectionTab);
            app.FolderListBox.Items = {};
            app.FolderListBox.Multiselect = 'on';
            app.FolderListBox.ValueChangedFcn = createCallbackFcn(app, @FolderListBoxValueChanged, true);
            app.FolderListBox.Value = {};
            app.ButtonSubmitFolders = uibutton(app.CellSelectionTab, 'push');
            app.ButtonSubmitFolders.ButtonPushedFcn = createCallbackFcn(app, @ButtonSubmitFoldersPushed, true);
            app.ButtonSubmitFolders.Text = 'Submit';
            app.TableInputFolder = uitable(app.CellSelectionTab);
            app.TableInputFolder.ColumnName = '';
            app.TableInputFolder.RowName = {};
            app.IWF_Logo_1 = uiaxes(app.CellSelectionTab);
            app.ProgrammFolderLabel = uilabel(app.CellSelectionTab);
            app.ProgrammFolderLabel.Text = 'Programm Folder';
            app.ProgrammDataFolderEditField = uieditfield(app.CellSelectionTab, 'text');
            app.BrowseProgrammButton = uibutton(app.CellSelectionTab, 'push');
            app.BrowseProgrammButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseProgrammButtonPushed, true);
            app.BrowseProgrammButton.Text = 'Browse';
            app.ImagesfoundinselectedFoldersLabel = uilabel(app.CellSelectionTab);
            app.ImagesfoundinselectedFoldersLabel.Text = 'Images found in selected Folders';
            app.ImageImportTab = uitab(app.TabGroup);
            app.ImageImportTab.Title = 'Image Import';
            app.IWF_Logo_2 = uiaxes(app.ImageImportTab);
            app.ConfigurationforpicturestakeninXDirectionPanel = uipanel(app.ImageImportTab);
            app.ConfigurationforpicturestakeninXDirectionPanel.Title = 'Configuration for pictures taken in X-Direction';
            app.MinimalelectrodelengthpxSliderLabel = uilabel(app.ConfigurationforpicturestakeninXDirectionPanel);
            app.MinimalelectrodelengthpxSliderLabel.Text = 'Minimal electrode length [px]';
            app.MinimalelectrodelengthpxSlider = uislider(app.ConfigurationforpicturestakeninXDirectionPanel);
            app.MinimalelectrodelengthpxSlider.Limits = [600 800];
            app.MinimalelectrodelengthpxSlider.ValueChangedFcn = createCallbackFcn(app, @MinimalelectrodelengthpxSliderValueChanged, true);
            app.MinimalelectrodelengthpxSlider.ValueChangingFcn = createCallbackFcn(app, @MinimalelectrodelengthpxSliderValueChanging, true);
            app.MinimalelectrodelengthpxSlider.Value = 680;
            app.DistancefromcornerimagesSliderLabel = uilabel(app.ConfigurationforpicturestakeninXDirectionPanel);
            app.DistancefromcornerimagesSliderLabel.Text = 'Distance from corner [images]';
            app.DistancefromcornerimagesSlider = uislider(app.ConfigurationforpicturestakeninXDirectionPanel);
            app.DistancefromcornerimagesSlider.Limits = [20 120];
            app.DistancefromcornerimagesSlider.MajorTicks = [20 30 40 50 60 70 80 90 100 110 120];
            app.DistancefromcornerimagesSlider.ValueChangedFcn = createCallbackFcn(app, @DistancefromcornerimagesSliderValueChanged, true);
            app.DistancefromcornerimagesSlider.ValueChangingFcn = createCallbackFcn(app, @DistancefromcornerimagesSliderValueChanging, true);
            app.DistancefromcornerimagesSlider.Value = 100;
            app.ConfigurationforpicturestakeninYDirectionPanel = uipanel(app.ImageImportTab);
            app.ConfigurationforpicturestakeninYDirectionPanel.Title = 'Configuration for pictures taken in Y-Direction';
            app.MInmalelectrodelengthpxSliderLabel = uilabel(app.ConfigurationforpicturestakeninYDirectionPanel);
            app.MInmalelectrodelengthpxSliderLabel.Text = 'MInmal electrode length [px]';
            app.MInmalelectrodelengthpxSlider = uislider(app.ConfigurationforpicturestakeninYDirectionPanel);
            app.MInmalelectrodelengthpxSlider.Limits = [400 600];
            app.MInmalelectrodelengthpxSlider.ValueChangedFcn = createCallbackFcn(app, @MInmalelectrodelengthpxSliderValueChanged, true);
            app.MInmalelectrodelengthpxSlider.ValueChangingFcn = createCallbackFcn(app, @MInmalelectrodelengthpxSliderValueChanging, true);
            app.MInmalelectrodelengthpxSlider.Value = 500;
            app.MinimaldistancetoedgepxSliderLabel = uilabel(app.ConfigurationforpicturestakeninYDirectionPanel);
            app.MinimaldistancetoedgepxSliderLabel.Text = 'Minimal distance to edge [px]';
            app.MinimaldistancetoedgepxSlider = uislider(app.ConfigurationforpicturestakeninYDirectionPanel);
            app.MinimaldistancetoedgepxSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.MinimaldistancetoedgepxSlider.ValueChangedFcn = createCallbackFcn(app, @MinimaldistancetoedgepxSliderValueChanged, true);
            app.MinimaldistancetoedgepxSlider.ValueChangingFcn = createCallbackFcn(app, @MinimaldistancetoedgepxSliderValueChanging, true);
            app.MinimaldistancetoedgepxSlider.Value = 20;
            app.ConfigurationforpicturestakeninZDirectionPanel = uipanel(app.ImageImportTab);
            app.ConfigurationforpicturestakeninZDirectionPanel.Title = 'Configuration for pictures taken in Z-Direction';
            app.NumerofPicturesSliderLabel = uilabel(app.ConfigurationforpicturestakeninZDirectionPanel);
            app.NumerofPicturesSliderLabel.Text = 'Numer of Pictures';
            app.NumerofPicturesSlider = uislider(app.ConfigurationforpicturestakeninZDirectionPanel);
            app.NumerofPicturesSlider.Limits = [10 50];
            app.NumerofPicturesSlider.MajorTicks = [10 15 20 25 30 35 40 45 50];
            app.NumerofPicturesSlider.ValueChangedFcn = createCallbackFcn(app, @NumerofPicturesSliderValueChanged, true);
            app.NumerofPicturesSlider.Value = 30;
            app.ShowHelperPreview = uiaxes(app.ImageImportTab);
            app.StartImportButton = uibutton(app.ImageImportTab, 'push');
            app.StartImportButton.ButtonPushedFcn = createCallbackFcn(app, @StartImportButtonPushed, true);
            app.StartImportButton.Text = 'Start Import';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end